classdef pokemonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        CorrBetweenStatsTab       matlab.ui.container.Tab
        Switch                    matlab.ui.control.Switch
        SwitchLabel               matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        YStatDropDown             matlab.ui.control.DropDown
        YStatDropDownLabel        matlab.ui.control.Label
        XStatDropDown             matlab.ui.control.DropDown
        XStatDropDownLabel        matlab.ui.control.Label
        PokemonTypeDropDown       matlab.ui.control.DropDown
        PokemonTypeDropDownLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        TypeDistrGensTab          matlab.ui.container.Tab
        GridLinesCheckBox         matlab.ui.control.CheckBox
        Slider                    matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        LookupTableBonus          matlab.ui.container.Tab
        EditField3                matlab.ui.control.EditField
        EditField3Label           matlab.ui.control.Label
        EditField2                matlab.ui.control.EditField
        EditField2Label           matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        EditFieldLabel            matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        pokeTable % Table of all the Pokemon
        pokeTypes % All the Types of Pokemon
        pokeColorDict % Color Dictionary
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial conditions
            app.pokeTable = readAndFilterTable();
            app.pokeTypes = getTypesList(app.pokeTable);
            app.pokeColorDict = matchTypesAndColors(app.pokeTable);
            pokeDistFunc(app,1,false)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CorrBetweenStatsTab = uitab(app.TabGroup);
            app.CorrBetweenStatsTab.Title = 'Corr. Between Stats';
            app.UIAxes = uiaxes(app.CorrBetweenStatsTab);
            title(app.UIAxes, 'Correlation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PokemonTypeDropDownLabel = uilabel(app.CorrBetweenStatsTab);
            app.PokemonTypeDropDownLabel.Text = 'Pokemon Type';
            app.PokemonTypeDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.XStatDropDownLabel = uilabel(app.CorrBetweenStatsTab);
            app.XStatDropDownLabel.Text = 'X- Stat';
            app.XStatDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.YStatDropDownLabel = uilabel(app.CorrBetweenStatsTab);
            app.YStatDropDownLabel.Text = 'Y-Stat';
            app.YStatDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.Image = uiimage(app.CorrBetweenStatsTab);
            app.Image2 = uiimage(app.CorrBetweenStatsTab);
            app.SwitchLabel = uilabel(app.CorrBetweenStatsTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.CorrBetweenStatsTab, 'slider');
            app.Switch.Items = {'Lowest', 'Highest'};
            app.Switch.Value = 'Lowest';
            app.TypeDistrGensTab = uitab(app.TabGroup);
            app.TypeDistrGensTab.Title = 'Type Distr. Gens';
            app.UIAxes2 = uiaxes(app.TypeDistrGensTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.TypeDistrGensTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.TypeDistrGensTab);
            app.GridLinesCheckBox = uicheckbox(app.TypeDistrGensTab);
            app.GridLinesCheckBox.Text = 'Grid Lines';
            app.LookupTableBonus = uitab(app.TabGroup);
            app.LookupTableBonus.Title = 'Lookup Table (Bonus)';
            app.Image3 = uiimage(app.LookupTableBonus);
            app.TextAreaLabel = uilabel(app.LookupTableBonus);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.LookupTableBonus);
            app.EditFieldLabel = uilabel(app.LookupTableBonus);
            app.EditFieldLabel.Text = 'Edit Field`';
            app.EditField = uieditfield(app.LookupTableBonus, 'text');
            app.EditField2Label = uilabel(app.LookupTableBonus);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.LookupTableBonus, 'text');
            app.EditField3Label = uilabel(app.LookupTableBonus);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.LookupTableBonus, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokemonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end